classdef PlantCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlantMetricCalculatorUIFigure  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SingleFileTab                  matlab.ui.container.Tab
        BrowseButton                   matlab.ui.control.Button
        spectrumAxes                   matlab.ui.control.UIAxes
        MeasurementsPanel              matlab.ui.container.Panel
        MicromolessecLabel             matlab.ui.control.Label
        PPFQuantaEditFieldLabel        matlab.ui.control.Label
        PPFQuanta                      matlab.ui.control.NumericEditField
        RadiantWattsLabel_2            matlab.ui.control.Label
        YPFRadiantPowerLabel           matlab.ui.control.Label
        YPFRadiant                     matlab.ui.control.NumericEditField
        ChromaticityEditFieldLabel     matlab.ui.control.Label
        Chromaticity                   matlab.ui.control.EditField
        PPFQuantaEffLabel              matlab.ui.control.Label
        PPFQuantaWatt                  matlab.ui.control.NumericEditField
        MicromolessecWLabel            matlab.ui.control.Label
        YPFRadiantEffLabel             matlab.ui.control.Label
        YPFRadiantWatt                 matlab.ui.control.NumericEditField
        RadiantWattsWLabel_2           matlab.ui.control.Label
        LumensLabel                    matlab.ui.control.Label
        LightOutputLabel               matlab.ui.control.Label
        LuxEdit                        matlab.ui.control.NumericEditField
        LightOutputEffEditFieldLabel   matlab.ui.control.Label
        LightOutputWatt                matlab.ui.control.NumericEditField
        LumensWLabel                   matlab.ui.control.Label
        RadiantWattsLabel              matlab.ui.control.Label
        PPFRadiantPowerLabel           matlab.ui.control.Label
        PPFRadiant                     matlab.ui.control.NumericEditField
        RadiantWattsWLabel             matlab.ui.control.Label
        PPFRadiantEffLabel             matlab.ui.control.Label
        PPFRadiantWatt                 matlab.ui.control.NumericEditField
        MicromolessecLabel_2           matlab.ui.control.Label
        YPFQuantaEditFieldLabel        matlab.ui.control.Label
        YPFQuanta                      matlab.ui.control.NumericEditField
        MicromolessecWLabel_2          matlab.ui.control.Label
        YPFQuantaEffLabel              matlab.ui.control.Label
        YPFQuantaWatt                  matlab.ui.control.NumericEditField
        MultiplierLable                matlab.ui.control.Label
        SpectrumFilePathLabel          matlab.ui.control.Label
        Filepath                       matlab.ui.control.EditField
        WattageEditFieldLabel          matlab.ui.control.Label
        Wattage                        matlab.ui.control.NumericEditField
        GroupFileTab                   matlab.ui.container.Tab
        DataTable                      matlab.ui.control.Table
        CalculateButton                matlab.ui.control.Button
        SaveLocationEditFieldLabel     matlab.ui.control.Label
        SaveLocationEditField          matlab.ui.control.EditField
        Button                         matlab.ui.control.Button
        FileTypeDropDownLabel          matlab.ui.control.Label
        FileTypeDropDown               matlab.ui.control.DropDown
        FileNameEditFieldLabel         matlab.ui.control.Label
        FileNameEditField              matlab.ui.control.EditField
        WorkInProgressLabel            matlab.ui.control.Label
    end

    
    properties (Access = public)
        spectrum % Description
        McCreeFromGraph = load('McCree1972.txt');
        h = 6.63e-34; % Joule seconds, Planck's constant
        c = 3.00e8; % meter/second
        Avo = 6.02e23; % Avogardo constant
        LastDir
        PPF
        PPFperW
        PPF_mole
        PPF_moleperW
        YPF
        YPFperW
        YPF_mole
        YPF_moleperW
        Lux
        xChrom
        yChrom
        Tc
    end
    
    methods (Access = public)
        
        function results = updatePlot(app)
            wave = app.spectrum(:,1);
            specFlux = app.spectrum(:,2);
            max_specFlux = max(specFlux);
            app.MultiplierLable.Text = ['Multiplier = ',num2str(max_specFlux,'%10.4e\n'),' (W/nm)'];
            normalSpecFlux = specFlux/max_specFlux;
            plot(app.spectrumAxes,wave,normalSpecFlux);
            xlabel(app.spectrumAxes,'Wavelength (nm)');
            ylabel(app.spectrumAxes,'Normalized Spectral Flux');
            
            updateMeasurments(app);
        end
         
        
        function results = updateMeasurments(app)
            %% Calculate Measurments
            wave = app.spectrum(:,1);
            specFlux = app.spectrum(:,2);
            watt = app.Wattage.Value;
            McCreePhoton = interp1(app.McCreeFromGraph(:,1),app.McCreeFromGraph(:,2),wave,'linear',0.0);
            McCreeWatt = McCreePhoton.*((wave*1e-9)/(app.h*app.c*app.Avo)); % convert growth/photon to growth/joule
            McCreeWatt = McCreeWatt/max(McCreeWatt);
            q1 = find(wave>=400,1,'first');
            q2 = find(wave<=700,1,'last');
            app.PPF = trapz(wave(q1:q2),specFlux(q1:q2)); % Watts
            app.PPFperW = app.PPF/watt;
            app.PPF_mole = 1e6*trapz(wave(q1:q2),specFlux(q1:q2).*(wave(q1:q2)*1e-9))/(app.h*app.c*app.Avo); % micromoles/s
            app.PPF_moleperW = app.PPF_mole/watt;
            app.YPF = trapz(wave,specFlux.*McCreeWatt); % Watts
            app.YPFperW = app.YPF/watt;
            app.YPF_mole = 1e6*trapz(wave,McCreePhoton.*specFlux.*(wave*1e-9))/(app.h*app.c*app.Avo); % micromoles/s
            app.YPF_moleperW = app.YPF_mole/watt;
            [app.Lux,app.xChrom,app.yChrom] = Lxy23Sep05([wave,specFlux]);
            app.Tc = CCT23Sep05(app.spectrum);
            
            %% Show Measurments
            app.PPFRadiant.Value = app.PPF;
            app.PPFQuanta.Value = app.PPF_mole;
            app.YPFRadiant.Value = app.YPF;
            app.YPFQuanta.Value = app.YPF_mole;
            app.PPFRadiantWatt.Value = app.PPFperW;
            app.PPFQuantaWatt.Value = app.PPF_moleperW;
            app.YPFRadiantWatt.Value = app.YPFperW;
            app.YPFQuantaWatt.Value = app.YPF_moleperW;
            app.LuxEdit.Value = app.Lux;
            app.LightOutputWatt.Value = app.Lux/watt;
            app.Chromaticity.Value = ['(',num2str(app.xChrom,3),',',num2str(app.yChrom,3),')'];
            
            
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function BrowseButtonPushed(app, event)
            if isempty(app.LastDir)
                [FileName,PathName,FilterIndex] = uigetfile({'*.*'},'Select Spectrum');
            else
                [FileName,PathName,FilterIndex] = uigetfile({[app.LastDir,'*.*']},'Select Spectrum');
            end
            if FilterIndex == 1
                app.Filepath.Value = fullfile(PathName,FileName);
                app.spectrum = load(fullfile(PathName,FileName));
                app.LastDir = PathName;
                updatePlot(app);
            end
        end

        % Callback function
        function FilepathValueChanged(app, event)
            value = app.Filepath.Value;
            if 2 == exist(value,'file')
                app.spectrum = load(value);
                updatePlot(app);
            end
        end

        % Value changed function: Wattage
        function WattageValueChanged(app, event)
            value = app.Wattage.Value;
            updateMeasurments(app);
        end

        % Cell selection callback: DataTable
        function DataTableCellSelection(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlantMetricCalculatorUIFigure = uifigure;
            app.PlantMetricCalculatorUIFigure.Name = 'Plant Metric Calculator';
            setAutoResize(app, app.PlantMetricCalculatorUIFigure, true)
            app.TabGroup = uitabgroup(app.PlantMetricCalculatorUIFigure);
            app.SingleFileTab = uitab(app.TabGroup);
            app.SingleFileTab.Title = 'Single File';
            app.SingleFileTab.Units = 'pixels';
            app.BrowseButton = uibutton(app.SingleFileTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.spectrumAxes = uiaxes(app.SingleFileTab);
            title(app.spectrumAxes, 'Spectrum');
            xlabel(app.spectrumAxes, 'Wavelength (nm)');
            ylabel(app.spectrumAxes, 'Power');
            app.MeasurementsPanel = uipanel(app.SingleFileTab);
            app.MeasurementsPanel.Title = 'Measurements';
            app.MicromolessecLabel = uilabel(app.MeasurementsPanel);
            app.MicromolessecLabel.Text = 'Micromoles/sec';
            app.PPFQuantaEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.PPFQuantaEditFieldLabel.Text = 'PPF Quanta';
            app.PPFQuanta = uieditfield(app.MeasurementsPanel, 'numeric');
            app.PPFQuanta.ValueDisplayFormat = '%5.3f';
            app.PPFQuanta.Editable = 'off';
            app.RadiantWattsLabel_2 = uilabel(app.MeasurementsPanel);
            app.RadiantWattsLabel_2.Text = 'Radiant Watts';
            app.YPFRadiantPowerLabel = uilabel(app.MeasurementsPanel);
            app.YPFRadiantPowerLabel.Text = 'YPF Radiant Power';
            app.YPFRadiant = uieditfield(app.MeasurementsPanel, 'numeric');
            app.YPFRadiant.ValueDisplayFormat = '%5.3f';
            app.YPFRadiant.Editable = 'off';
            app.ChromaticityEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.ChromaticityEditFieldLabel.Text = 'Chromaticity';
            app.Chromaticity = uieditfield(app.MeasurementsPanel, 'text');
            app.Chromaticity.Editable = 'off';
            app.PPFQuantaEffLabel = uilabel(app.MeasurementsPanel);
            app.PPFQuantaEffLabel.Text = 'PPF Quanta Eff.';
            app.PPFQuantaWatt = uieditfield(app.MeasurementsPanel, 'numeric');
            app.PPFQuantaWatt.ValueDisplayFormat = '%5.3f';
            app.PPFQuantaWatt.Editable = 'off';
            app.MicromolessecWLabel = uilabel(app.MeasurementsPanel);
            app.MicromolessecWLabel.Text = 'Micromoles/sec/W';
            app.YPFRadiantEffLabel = uilabel(app.MeasurementsPanel);
            app.YPFRadiantEffLabel.Text = 'YPF Radiant Eff.';
            app.YPFRadiantWatt = uieditfield(app.MeasurementsPanel, 'numeric');
            app.YPFRadiantWatt.ValueDisplayFormat = '%5.3f';
            app.YPFRadiantWatt.Editable = 'off';
            app.RadiantWattsWLabel_2 = uilabel(app.MeasurementsPanel);
            app.RadiantWattsWLabel_2.Text = 'Radiant Watts/W';
            app.LumensLabel = uilabel(app.MeasurementsPanel);
            app.LumensLabel.Text = 'Lumens';
            app.LightOutputLabel = uilabel(app.MeasurementsPanel);
            app.LightOutputLabel.Text = 'Light Output';
            app.LuxEdit = uieditfield(app.MeasurementsPanel, 'numeric');
            app.LuxEdit.ValueDisplayFormat = '%5.2f';
            app.LuxEdit.Editable = 'off';
            app.LightOutputEffEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.LightOutputEffEditFieldLabel.Text = 'Light Output Eff.';
            app.LightOutputWatt = uieditfield(app.MeasurementsPanel, 'numeric');
            app.LightOutputWatt.ValueDisplayFormat = '%5.3f';
            app.LumensWLabel = uilabel(app.MeasurementsPanel);
            app.LumensWLabel.Text = 'Lumens/W';
            app.RadiantWattsLabel = uilabel(app.MeasurementsPanel);
            app.RadiantWattsLabel.Text = 'Radiant Watts';
            app.PPFRadiantPowerLabel = uilabel(app.MeasurementsPanel);
            app.PPFRadiantPowerLabel.Text = 'PPF Radiant Power';
            app.PPFRadiant = uieditfield(app.MeasurementsPanel, 'numeric');
            app.PPFRadiant.ValueDisplayFormat = '%5.3f';
            app.PPFRadiant.Editable = 'off';
            app.RadiantWattsWLabel = uilabel(app.MeasurementsPanel);
            app.RadiantWattsWLabel.Text = 'Radiant Watts/W';
            app.PPFRadiantEffLabel = uilabel(app.MeasurementsPanel);
            app.PPFRadiantEffLabel.Text = 'PPF Radiant Eff.';
            app.PPFRadiantWatt = uieditfield(app.MeasurementsPanel, 'numeric');
            app.PPFRadiantWatt.ValueDisplayFormat = '%5.3f';
            app.PPFRadiantWatt.Editable = 'off';
            app.MicromolessecLabel_2 = uilabel(app.MeasurementsPanel);
            app.MicromolessecLabel_2.Text = 'Micromoles/sec';
            app.YPFQuantaEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.YPFQuantaEditFieldLabel.Text = 'YPF Quanta';
            app.YPFQuanta = uieditfield(app.MeasurementsPanel, 'numeric');
            app.YPFQuanta.ValueDisplayFormat = '%5.3f';
            app.YPFQuanta.Editable = 'off';
            app.MicromolessecWLabel_2 = uilabel(app.MeasurementsPanel);
            app.MicromolessecWLabel_2.Text = 'Micromoles/sec/W';
            app.YPFQuantaEffLabel = uilabel(app.MeasurementsPanel);
            app.YPFQuantaEffLabel.Text = 'YPF Quanta Eff.';
            app.YPFQuantaWatt = uieditfield(app.MeasurementsPanel, 'numeric');
            app.YPFQuantaWatt.ValueDisplayFormat = '%5.3f';
            app.YPFQuantaWatt.Editable = 'off';
            app.MultiplierLable = uilabel(app.SingleFileTab);
            app.MultiplierLable.Text = 'Multiplier = N/A';
            app.SpectrumFilePathLabel = uilabel(app.SingleFileTab);
            app.SpectrumFilePathLabel.Text = 'Spectrum File Path';
            app.Filepath = uieditfield(app.SingleFileTab, 'text');
            app.Filepath.ValueChangedFcn = createCallbackFcn(app, @FilepathValueChanged, true);
            app.Filepath.Value = 'File Name...';
            app.WattageEditFieldLabel = uilabel(app.SingleFileTab);
            app.WattageEditFieldLabel.Text = 'Wattage';
            app.Wattage = uieditfield(app.SingleFileTab, 'numeric');
            app.Wattage.LowerLimitInclusive = 'off';
            app.Wattage.ValueChangedFcn = createCallbackFcn(app, @WattageValueChanged, true);
            app.Wattage.Limits = [0 Inf];
            app.Wattage.Value = 1;
            app.GroupFileTab = uitab(app.TabGroup);
            app.GroupFileTab.Title = 'Group File';
            app.GroupFileTab.Units = 'pixels';
            app.DataTable = uitable(app.GroupFileTab);
            app.DataTable.ColumnName = {'Spectrum File'; 'Wattage'; 'PPF_R'; 'PPF__RperW'; 'PPF_Q'; 'PPF_QperW'; 'YPF_R'; 'YPF_RperW'; 'YPF_Q'; 'YPF_QperW'; 'Chromaticity'};
            app.DataTable.RowName = {};
            app.DataTable.ColumnEditable = [true true false false false false false false false false false];
            app.DataTable.CellSelectionCallback = createCallbackFcn(app, @DataTableCellSelection, true);
            app.CalculateButton = uibutton(app.GroupFileTab, 'push');
            app.CalculateButton.Text = 'Calculate';
            app.SaveLocationEditFieldLabel = uilabel(app.GroupFileTab);
            app.SaveLocationEditFieldLabel.Text = 'Save Location';
            app.SaveLocationEditField = uieditfield(app.GroupFileTab, 'text');
            app.Button = uibutton(app.GroupFileTab, 'push');
            app.FileTypeDropDownLabel = uilabel(app.GroupFileTab);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.FileTypeDropDown = uidropdown(app.GroupFileTab);
            app.FileTypeDropDown.Items = {'*.csv', '*.txt (tab deliminated)', '*.xls', '*.xlsx'};
            app.FileTypeDropDown.Value = '*.xlsx';
            app.FileNameEditFieldLabel = uilabel(app.GroupFileTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.GroupFileTab, 'text');
            app.WorkInProgressLabel = uilabel(app.GroupFileTab);
            app.WorkInProgressLabel.Text = 'Work In Progress';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PlantCalculator()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlantMetricCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlantMetricCalculatorUIFigure)
        end
    end
end